classdef demo10 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        DropDown       matlab.ui.control.DropDown
        DropDownLabel  matlab.ui.control.Label
        UIAxes         matlab.ui.control.UIAxes
    end


    methods (Access = private)

        function func(app,value)
            if value == "Option 1"
                x = 1:0.1:10;
                y1 = sin(x);
                plot(app.UIAxes,x,y1);
            elseif value == "Option 2"
                x = 1:0.1:10;
                y2 = cos(x);
                plot(app.UIAxes,x,y2);
            else
                return;
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            value = app.DropDown.Value;
            if value == "Option 1"
                func(app,value);
            elseif value == "Option 2"
                func(app,value);
            else
                return;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.DropDownLabel = uilabel(app.UIFigure);
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo10

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end