classdef demo12 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        Gauge             matlab.ui.control.LinearGauge
        GaugeLabel        matlab.ui.control.Label
        Lamp              matlab.ui.control.Lamp
        LampLabel         matlab.ui.control.Label
        GroupButtonGroup  matlab.ui.container.ButtonGroup
        Button_3          matlab.ui.control.RadioButton
        Button_2          matlab.ui.control.RadioButton
        Button            matlab.ui.control.RadioButton
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Selection changed function: GroupButtonGroup
        function GroupButtonGroupSelectionChanged(app, event)
            selectedButton = app.GroupButtonGroup.SelectedObject;
            switch selectedButton.Text
                case '1'
                    app.Lamp.Color = '0.00,1.00,0.00';
                    app.Gauge.Value = 1;
                case '2'
                    app.Lamp.Color = '0.07,0.62,1.00';
                    app.Gauge.Value = 2;                    
                case '3'
                    app.Lamp.Color = '1.00,0.00,0.00';    
                    app.Gauge.Value = 3;                    
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GroupButtonGroup = uibuttongroup(app.UIFigure);
            app.GroupButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GroupButtonGroupSelectionChanged, true);
            app.GroupButtonGroup.Title = 'Group';
            app.Button = uiradiobutton(app.GroupButtonGroup);
            app.Button.Text = '1';
            app.Button.Value = true;
            app.Button_2 = uiradiobutton(app.GroupButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uiradiobutton(app.GroupButtonGroup);
            app.Button_3.Text = '3';
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.GaugeLabel = uilabel(app.UIFigure);
            app.GaugeLabel.Text = 'Gauge';
            app.Gauge = uigauge(app.UIFigure, 'linear');
            app.Gauge.Limits = [0 3];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo12

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end