classdef demo14 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        DeletetableEditField  matlab.ui.control.NumericEditField
        DeleteEditFieldLabel  matlab.ui.control.Label
        DeletetableButton     matlab.ui.control.Button
        GenerateButton        matlab.ui.control.Button
        yEditField            matlab.ui.control.NumericEditField
        yEditFieldLabel       matlab.ui.control.Label
        xEditField            matlab.ui.control.NumericEditField
        xEditFieldLabel       matlab.ui.control.Label
        readtableButton       matlab.ui.control.Button
        UITable               matlab.ui.control.Table
        UIAxes                matlab.ui.control.UIAxes
    end

    
    properties (Access = public)
        t; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: readtableButton
        function readtableButtonPushed(app, event)
            app.t = readtable("demo14_data.txt");
            app.UITable.Data = app.t;
            %app.UITable.ColumnName = ['Number';'Random'];
            %app.UITable.ColumnName = t.Properties.VariableNames;
            app.UITable.ColumnName{1} = 'Number';
            app.UITable.ColumnName{2} = 'Random';
            
            X = table2array(app.t(:,'x'));
            Y = table2array(app.t(:,'y'));
            scatter(app.UIAxes,X,Y,'r','filled');
        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)
            
            new_x = app.xEditField.Value;
            app.yEditField.Value = rand(1,1);
            new_y = app.yEditField.Value;
            new = {new_x new_y};
            Size_table = size(app.t);
            for i = 1:Size_table(1)
                if app.UITable.Data ~= 0
                    return;
                else
                    app.UITable.Data(i,:) = [app.t;new];
                    return;
                end
                
            end
            
        end

        % Button pushed function: DeletetableButton
        function DeletetableButtonPushed(app, event)
            row_n = app.DeletetableEditField.Value;
            app.UITable.Data(row_n,:) = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Random')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'x'; 'y'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.readtableButton = uibutton(app.UIFigure, 'push');
            app.readtableButton.ButtonPushedFcn = createCallbackFcn(app, @readtableButtonPushed, true);
            app.readtableButton.Text = 'read table';
            app.xEditFieldLabel = uilabel(app.UIFigure);
            app.xEditFieldLabel.Text = 'x';
            app.xEditField = uieditfield(app.UIFigure, 'numeric');
            app.yEditFieldLabel = uilabel(app.UIFigure);
            app.yEditFieldLabel.Text = 'y';
            app.yEditField = uieditfield(app.UIFigure, 'numeric');
            app.GenerateButton = uibutton(app.UIFigure, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate';
            app.DeletetableButton = uibutton(app.UIFigure, 'push');
            app.DeletetableButton.ButtonPushedFcn = createCallbackFcn(app, @DeletetableButtonPushed, true);
            app.DeletetableButton.Text = 'Delete';
            app.DeleteEditFieldLabel = uilabel(app.UIFigure);
            app.DeleteEditFieldLabel.Text = 'Delete';
            app.DeletetableEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo14

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end