classdef demo2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        TipEditField          matlab.ui.control.EditField
        TipLabel              matlab.ui.control.Label
        Lamp                  matlab.ui.control.Lamp
        LampLabel             matlab.ui.control.Label
        TemperatureEditField  matlab.ui.control.NumericEditField
        TemperatureLabel      matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: TemperatureEditField
        function TemperatureEditFieldValueChanged(app, event)
            value = app.TemperatureEditField.Value;
            if value>=500
                app.Lamp.Color = "1.00,0.00,0.00";
                app.TipEditField.Value = "Too High";
            elseif value<500 && value >=300
                app.Lamp.Color = "1.00,0.41,0.16";
                app.TipEditField.Value = "Suitable"; 
            else
                app.Lamp.Color = "0.07,0.62,1.00";
                app.TipEditField.Value = "Too Low"; 
            end
        end

        % Value changed function: TipEditField
        function TipEditFieldValueChanged(app, event)
            value = app.TipEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TemperatureLabel = uilabel(app.UIFigure);
            app.TemperatureLabel.Text = 'Temperature:';
            app.TemperatureEditField = uieditfield(app.UIFigure, 'numeric');
            app.TemperatureEditField.ValueChangedFcn = createCallbackFcn(app, @TemperatureEditFieldValueChanged, true);
            app.LampLabel = uilabel(app.UIFigure);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Tooltip = {'Input the temperature, Tem>500,Red;'; 'Tem>=300,Orange;'; 'Temp<300,Blue'};
            app.TipLabel = uilabel(app.UIFigure);
            app.TipLabel.Text = 'Tip:';
            app.TipEditField = uieditfield(app.UIFigure, 'text');
            app.TipEditField.ValueChangedFcn = createCallbackFcn(app, @TipEditFieldValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end