classdef demo6 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure        matlab.ui.Figure
        ExitButton      matlab.ui.control.Button
        AboutButton     matlab.ui.control.Button
        ReadFileButton  matlab.ui.control.Button
        UIAxes_2        matlab.ui.control.UIAxes
        UIAxes          matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AboutButton
        function AboutButtonPushed(app, event)
            msgbox('This app helps transfer the image to gray','About','help');
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            choice = questdlg('Are you sure to exit?','Exit','Yes','No','Yes');
            switch choice
                case 'Yes'
                    delete(app.UIFigure);%close the windows
                    return;
                case 'No'
                    return;
            end
        end

        % Button pushed function: ReadFileButton
        function ReadFileButtonPushed(app, event)
            [filename,pathname] = uigetfile({'*.jpg';'*.bmp';'*.png';'*,*'},'Choose image');
            if isequal(filename,0)||isequal(pathname,0)
                errordlg('Error! No file input');
            else
                file = strcat(pathname,filename);%absolute file path
            end
            % original image
            im = imread(file);
            imshow(im,'Parent',app.UIAxes);
            msgbox('Successfully read','Tip','help');
            % gray image
            gray_image = rgb2gray(im);
            imshow(gray_image,'Parent',app.UIAxes_2);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original Image')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Grayscale Image')
            zlabel(app.UIAxes_2, 'Z')
            app.ReadFileButton = uibutton(app.UIFigure, 'push');
            app.ReadFileButton.ButtonPushedFcn = createCallbackFcn(app, @ReadFileButtonPushed, true);
            app.ReadFileButton.Text = 'Read File';
            app.AboutButton = uibutton(app.UIFigure, 'push');
            app.AboutButton.ButtonPushedFcn = createCallbackFcn(app, @AboutButtonPushed, true);
            app.AboutButton.Text = 'About';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Tooltip = {'Click to exit'};
            app.ExitButton.Text = 'Exit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo6

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end